classdef flys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        load_data_panel                 matlab.ui.container.Panel
        stacks_tab_group                matlab.ui.container.TabGroup
        browse_tab                      matlab.ui.container.Tab
        fictracTab                      matlab.ui.container.Tab
        match_tab                       matlab.ui.container.Tab
        natural_sort_check_box          matlab.ui.control.CheckBox
        stack_compute_pool_panel        matlab.ui.container.Panel
        stack_compute_pool_list_box     matlab.ui.control.ListBox
        browse_files_button             matlab.ui.control.Button
        use_selected_button             matlab.ui.control.Button
        list_matches_button             matlab.ui.control.Button
        matches_list_box                matlab.ui.control.ListBox
        matching_style_drop_down        matlab.ui.control.DropDown
        matching_style_drop_down_label  matlab.ui.control.Label
        suffix_drop_down                matlab.ui.control.DropDown
        suffix_drop_down_label          matlab.ui.control.Label
        trial_edit_field                matlab.ui.control.EditField
        trial_edit_field_label          matlab.ui.control.Label
        fly_edit_field                  matlab.ui.control.EditField
        fly_edit_field_label            matlab.ui.control.Label
        date_edit_field                 matlab.ui.control.EditField
        date_edit_field_label           matlab.ui.control.Label
        parent_path_edit_field          matlab.ui.control.EditField
        parent_path_edit_field_label    matlab.ui.control.Label
        parent_path_button              matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        pathenv = getpathenv()
        startingdir = getpathenv()
        stack_compute_pool_list_box_items_original = [];
        valid_fnextensions = {'*.mat';'*.tif'}
        valid_fnsuffixes = {'raw', 'cmrg', 'cmrg_dcdn', 'bksb_cmrg', 'bksb_cmrg_dcdn', 'bksb_cmrg_dcdn_nosn'}
    end
    
    methods (Access = private)
        
        function results = fuk(app)
            ui.mn.pthparent_o2 = '';
            pthstacks = find_preprocessed_files( ...
                pthparent_local=app.parent_path_edit_field.Value, ...
                pthparent_o2=ui.mn.pthparent_o2, ...
                valid_fnsuffixes=app.suffix_drop_down.Value, ...
                recdate=app.date_edit_field.Value, ...
                fly=app.fly_edit_field.Value, ...
                trial=app.trial_edit_field.Value, ...
                suffix=app.suffix_drop_down.Value, ...
                fnspec_matching_style=app.matching_style_drop_down.Value);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, username)
            arguments
                app
                username = []
            end
            if isempty(username)
                app.parent_path_edit_field.Value = '';
            elseif strcmp(username, 'carl')
                app.startingdir = '~/stacks';
                app.parent_path_edit_field.Value = app.startingdir;
            end
            app.suffix_drop_down.Items = cat(2, '*', app.valid_fnsuffixes);
            app.matches_list_box.Items = {''};
            app.stack_compute_pool_list_box.Items = {''};
            app.date_edit_field.Value = '*';
            app.fly_edit_field.Value = '*';
            app.trial_edit_field.Value = '*';
            app.matching_style_drop_down.Items = {'any', 'each'};
        end

        % Button pushed function: parent_path_button
        function parent_path_button_pushed(app, event)
            app.parent_path_edit_field.Value = uigetdir(app.startingdir, 'select a directory containing all recordings you want to analyze');
        end

        % Button pushed function: list_matches_button
        function list_matches_button_pressed(app, event)
            ui.mn.pthparent_o2 = '';
            pthstacks = find_preprocessed_files( ...
                pthparent_local=app.parent_path_edit_field.Value, ...
                pthparent_o2=ui.mn.pthparent_o2, ...
                valid_fnsuffixes=app.valid_fnsuffixes, ...
                recdate=app.date_edit_field.Value, ...
                fly=app.fly_edit_field.Value, ...
                trial=app.trial_edit_field.Value, ...
                suffix=app.suffix_drop_down.Value, ...
                fnspec_matching_style=app.matching_style_drop_down.Value);
            app.matches_list_box.Items = pthstacks;
        end

        % Button pushed function: use_selected_button
        function use_selected_button_pushed(app, event)
            selected = app.matches_list_box.Value;
            if isempty(cell2mat(app.stack_compute_pool_list_box.Items))
                app.stack_compute_pool_list_box.Items = selected;
            else
                app.stack_compute_pool_list_box.Items(end+numel(selected)) = selected;
            end
        end

        % Button pushed function: browse_files_button
        function browse_files_button_pushed(app, event)
            multiselectmode = 'on';
            selected = [];
            for k = 1:numel(app.valid_fnextensions)
                getfile_filter = strcat(app.parent_path_edit_field.Value, filesep, '**', filesep, app.valid_fnextensions(k));
                msg = sprintf("select one or more " + app.valid_fnextensions(k) + ' stacks (each extension must be done separately, unfortunately)');
                [fntmp, fldrtmp] = uigetfile(getfile_filter, msg, app.startingdir, 'MultiSelect', multiselectmode);
                tmp = fullfile(fldrtmp, fntmp);
                if ~iscell(tmp)
                    tmp = {tmp};
                end
                selected = cat(2, selected, tmp);
            end
            xflt = contains(selected, app.valid_fnsuffixes);
            if any(xflt)
                selected = selected(xflt);
            else
                selected = 'INVALID';
            end
            selected = unique(selected, 'stable');

            %INSERT FUNCTION TO REMOVE DUPES ACROSS TIF AND MAT or FLYG SCOPA

            if isempty(cell2mat(app.stack_compute_pool_list_box.Items))
                app.stack_compute_pool_list_box.Items = selected;
            else
                app.stack_compute_pool_list_box.Items(end+1:end+numel(selected)) = selected;
            end
            app.stack_compute_pool_list_box.Items = unique(app.stack_compute_pool_list_box.Items, 'stable');
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            % function processKeyPress(src,event,lb)
            %     if src.CurrentObject == lb
            %         disp("List box key pressed")
            %     end
            % end
        end

        % Value changed function: natural_sort_check_box
        function natural_sort_check_boxValueChanged(app, event)
            if ~isempty(cell2mat(app.stack_compute_pool_list_box.Items))
                if isempty(app.stack_compute_pool_list_box_items_original)
                    app.stack_compute_pool_list_box_items_original = app.stack_compute_pool_list_box.Items;
                end
                if app.natural_sort_check_box.Value
                    app.stack_compute_pool_list_box.Items = natsortfiles(app.stack_compute_pool_list_box.Items);
                else
                    app.stack_compute_pool_list_box.Items = app.stack_compute_pool_list_box_items_original;
                end
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {691, 691, 691};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.load_data_panel.Layout.Row = 2;
                app.load_data_panel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {691, 691};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.load_data_panel.Layout.Row = 2;
                app.load_data_panel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {836, '1x', 12};
                app.load_data_panel.Layout.Row = 1;
                app.load_data_panel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.load_data_panel = uipanel(app.GridLayout);
            app.load_data_panel.Title = 'LOAD DATA';
            app.stacks_tab_group = uitabgroup(app.load_data_panel);
            app.match_tab = uitab(app.stacks_tab_group);
            app.match_tab.Title = 'stacks';
            app.parent_path_button = uibutton(app.match_tab, 'push');
            app.parent_path_button.ButtonPushedFcn = createCallbackFcn(app, @parent_path_button_pushed, true);
            app.parent_path_button.Text = '...';
            app.parent_path_edit_field_label = uilabel(app.match_tab);
            app.parent_path_edit_field_label.Text = 'parent path';
            app.parent_path_edit_field = uieditfield(app.match_tab, 'text');
            app.date_edit_field_label = uilabel(app.match_tab);
            app.date_edit_field_label.Text = 'date';
            app.date_edit_field = uieditfield(app.match_tab, 'text');
            app.fly_edit_field_label = uilabel(app.match_tab);
            app.fly_edit_field_label.Text = 'fly';
            app.fly_edit_field = uieditfield(app.match_tab, 'text');
            app.trial_edit_field_label = uilabel(app.match_tab);
            app.trial_edit_field_label.Text = 'trial';
            app.trial_edit_field = uieditfield(app.match_tab, 'text');
            app.suffix_drop_down_label = uilabel(app.match_tab);
            app.suffix_drop_down_label.Text = 'suffix';
            app.suffix_drop_down = uidropdown(app.match_tab);
            app.matching_style_drop_down_label = uilabel(app.match_tab);
            app.matching_style_drop_down_label.Text = 'matching style';
            app.matching_style_drop_down = uidropdown(app.match_tab);
            app.matches_list_box = uilistbox(app.match_tab);
            app.matches_list_box.Multiselect = 'on';
            app.matches_list_box.Tooltip = {'app.matches_list_box.Items'};
            app.matches_list_box.Value = {'Item 1'};
            app.list_matches_button = uibutton(app.match_tab, 'push');
            app.list_matches_button.ButtonPushedFcn = createCallbackFcn(app, @list_matches_button_pressed, true);
            app.list_matches_button.Text = 'list matches';
            app.use_selected_button = uibutton(app.match_tab, 'push');
            app.use_selected_button.ButtonPushedFcn = createCallbackFcn(app, @use_selected_button_pushed, true);
            app.use_selected_button.Text = 'use selected';
            app.browse_files_button = uibutton(app.match_tab, 'push');
            app.browse_files_button.ButtonPushedFcn = createCallbackFcn(app, @browse_files_button_pushed, true);
            app.browse_files_button.Text = 'browse files';
            app.stack_compute_pool_panel = uipanel(app.match_tab);
            app.stack_compute_pool_panel.Title = 'stack compute pool';
            app.stack_compute_pool_list_box = uilistbox(app.stack_compute_pool_panel);
            app.stack_compute_pool_list_box.Tooltip = {'stacks to be analyzed'};
            app.natural_sort_check_box = uicheckbox(app.match_tab);
            app.natural_sort_check_box.ValueChangedFcn = createCallbackFcn(app, @natural_sort_check_boxValueChanged, true);
            app.natural_sort_check_box.Text = 'natural sort';
            app.browse_tab = uitab(app.stacks_tab_group);
            app.browse_tab.Title = 'daq';
            app.fictracTab = uitab(app.stacks_tab_group);
            app.fictracTab.Title = 'fictrac';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flys(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end